classdef hum1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StartServingButton         matlab.ui.control.Button
        StartFillingButton         matlab.ui.control.Button
        TaskEditField              matlab.ui.control.NumericEditField
        TaskEditFieldLabel         matlab.ui.control.Label
        FinishServingButton        matlab.ui.control.Button
        FinishFillingButton        matlab.ui.control.Button
        RobotStatusEditField       matlab.ui.control.EditField
        RobotStatusEditFieldLabel  matlab.ui.control.Label
        TimeEditField              matlab.ui.control.NumericEditField
        TimeEditFieldLabel         matlab.ui.control.Label
        HumanIDEditField           matlab.ui.control.NumericEditField
        HumanIDEditFieldLabel      matlab.ui.control.Label
        WaitingTimeButtonGroup     matlab.ui.container.ButtonGroup
        TooShortButton             matlab.ui.control.ToggleButton
        ShortButton                matlab.ui.control.ToggleButton
        OkButton_2                 matlab.ui.control.ToggleButton
        LongButton                 matlab.ui.control.ToggleButton
        TooLongButton              matlab.ui.control.ToggleButton
        VelocityRobotReachingButtonGroup  matlab.ui.container.ButtonGroup
        VerySlowButton             matlab.ui.control.ToggleButton
        SlowButton                 matlab.ui.control.ToggleButton
        OkButton                   matlab.ui.control.ToggleButton
        FastButton                 matlab.ui.control.ToggleButton
        VeryfastButton             matlab.ui.control.ToggleButton
        HumanInterfaceLabel        matlab.ui.control.Label
        ConfirmButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        TimeFilling
        TimeHumFilling
        alpha
        VelocityRobot
        callingapp
        TimerValue
        FinishFilling
        StartFilling
        FinishServing
        StartServing
        confirmModif
        confirm
        TimeFinishFilling
        TimeStartFilling
        TimeFinishServing
        TimeStartServing
        NumTask
        pub 
        msg
        timingData
        subTime
        subHumanData
        task
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, HumanID)
            global HumID
            %close all
            
            addpath('/home/jorand/differentialdrive_ws/src/matlab_msg_gen_ros1/glnxa64/install/m')
            savepath
            home = 1;

            app.HumanIDEditField.Value = HumanID;
            

            % Create a publisher for your custom message
            app.pub = rospublisher(sprintf("/human_robot_interaction%d", HumanID), 'diff_drive_robot/HumanRobotInteraction');
            app.msg = rosmessage(app.pub);
            app.subTime = receive(rossubscriber('/Timing', 'std_msgs/Float64'), 1).Data 
            app.subHumanData = rossubscriber(sprintf("/human_robot_interaction%d", HumanID), 'diff_drive_robot/HumanRobotInteraction')
            %app.timingData = receive(app.subTime, 1).Data

            app.VelocityRobot = 0;
            app.TimeFilling = 0;
            app.TimeHumFilling = [];
            app.TimeFinishFilling = [];
            app.TimeStartFilling = [];
            app.TimeFinishServing = [];
            app.TimeStartServing = [];
            HumID = 1;
            app.TimerValue = 11;

            app.msg.HumanID = HumanID;
            app.msg.RobotVelocity = 0;
            app.msg.WaitingTime = 0; 
            app.msg.StartFilling = app.subHumanData.LatestMessage.StartFilling; %[0.0, 162.0, 314.0];
            app.msg.FinishFilling = app.subHumanData.LatestMessage.FinishFilling; %[150.0, 312.0, 464.0];
            app.msg.TimeFilling = app.subHumanData.LatestMessage.TimeFilling; %[150.0, 150.0, 150.0];
            app.msg.TimeServing = app.subHumanData.LatestMessage.TimeServing; %[7.0, 7.0, 7.0];
            app.msg.StartServing = 0;
            app.msg.FinishServing = 0;
            app.msg.ConfirmServing = 0;
            app.msg.ConfirmFilling = 0;
            app.msg.Task = 1;

            app.TaskEditField.Value = app.msg.Task;
            % Publish the message
            %send(app.pub, app.msg);
        end

        % Selection changed function: WaitingTimeButtonGroup
        function WaitingTimeButtonGroupSelectionChanged(app, event)
            global HumID
            switch true
                case app.TooShortButton.Value
                    %app.alpha = 0.5
                    app.msg.WaitingTime = 0.5;
                    send(app.pub, app.msg);
                case app.ShortButton.Value
                    %app.alpha = 0.8
                    app.msg.WaitingTime = 0.8;
                    send(app.pub, app.msg);
                case app.OkButton_2.Value
                    %app.alpha = 1
                    app.msg.WaitingTime = 1;
                    send(app.pub, app.msg);
                case app.LongButton.Value
                    %app.alpha = 2
                    app.msg.WaitingTime = 2;
                    send(app.pub, app.msg);
                case app.TooLongButton.Value
                    %app.alpha = 3
                    app.msg.WaitingTime = 3;
                    send(app.pub, app.msg);
            end
        end

        % Selection changed function: VelocityRobotReachingButtonGroup
        function VelocityRobotReachingButtonGroupSelectionChanged(app, event)
            switch true
                case app.VerySlowButton.Value
                    %app.VelocityRobot = 2
                    app.msg.RobotVelocity = 2;
                    send(app.pub, app.msg);
                case app.SlowButton.Value
                    %app.VelocityRobot = 1
                    pp.msg.RobotVelocity = 1;
                    send(app.pub, app.msg);
                case app.OkButton.Value
                    %app.VelocityRobot = 0
                    app.msg.RobotVelocity = 0;
                    send(app.pub, app.msg);
                case app.FastButton.Value
                    %app.VelocityRobot = -0.2
                    app.msg.RobotVelocity = -0.2;
                    send(app.pub, app.msg);
                case app.VeryfastButton.Value
                    %app.VelocityRobot = -0.5
                    app.msg.RobotVelocity = -0.5;
                    send(app.pub, app.msg);
            end          
        end

        % Value changed function: HumanIDEditField
        function HumanIDEditFieldValueChanged(app, event)
            value = app.HumanIDEditField.Value;
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = receive(rossubscriber('/Timing', 'std_msgs/Float64'), 0.1).Data;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)

            app.confirmModif = 1;
            
            % Gather data into a structure
            dataToSave = struct;
            dataToSave.HumanID = app.HumanIDEditField.Value;
            dataToSave.TimerValue = app.TimerValue;
            dataToSave.alpha = app.alpha;
            dataToSave.VelocityRobot = app.VelocityRobot;
            %TimingSub = rossubscriber('/Timing', 'std_msgs/Float64').LatestMessage.Data;
            app.TimeEditField.Value = rossubscriber('/Timing', 'std_msgs/Float64');
            %app.TimeEditField.Value = int32(receive(TimingSub, 1).Data)
        end

        % Value changed function: RobotStatusEditField
        function RobotStatusEditFieldValueChanged(app, event)
            value = app.RobotStatusEditField.Value;
        end

        % Button pushed function: FinishFillingButton
        function FinishFillingButtonPushed(app, event)
            %app.FinishFilling = 1;
            %app.confirmModif = 0;
            %app.msg.Data = app.TimeEditField.Value; % Get the value from an edit field or another component
            %app.msg.Task = app.subHumanData.LatestMessage.Task;
            %app.msg.FinishFilling = app.subHumanData.LatestMessage.FinishFilling;
            %app.msg.StartFilling = app.subHumanData.LatestMessage.StartFilling;
            %app.msg.FinishFilling(app.msg.Task) = receive(rossubscriber('/Timing', 'std_msgs/Float64'), 0.1).Data;%app.subTime;
            
            % send(app.pub, app.msg);

            app.msg.Task = app.subHumanData.LatestMessage.Task;
            app.msg.FinishFilling = app.subHumanData.LatestMessage.FinishFilling;
            app.msg.StartFilling = app.subHumanData.LatestMessage.StartFilling;
            app.msg.FinishServing = app.subHumanData.LatestMessage.FinishServing;
            app.msg.StartServing = app.subHumanData.LatestMessage.StartServing;
            app.msg.FinishFilling(app.msg.Task) = receive(rossubscriber('/Timing', 'std_msgs/Float64'), 0.1).Data;%app.subTime;
            app.msg.ConfirmFilling = 1;
            send(app.pub, app.msg);
        end

        % Value changed function: TaskEditField
        function TaskEditFieldValueChanged(app, event)
            value = app.subHumanData.LatestMessage.Task;
        end

        % Button pushed function: StartFillingButton
        function StartFillingButtonPushed(app, event)
            %app.StartFilling = 1;
            app.msg.Task = app.subHumanData.LatestMessage.Task;
            app.msg.FinishFilling = app.subHumanData.LatestMessage.FinishFilling;
            app.msg.StartFilling = app.subHumanData.LatestMessage.StartFilling;
            app.msg.FinishServing = app.subHumanData.LatestMessage.FinishServing;
            app.msg.StartServing = app.subHumanData.LatestMessage.StartServing;
            app.msg.StartFilling(app.msg.Task) = receive(rossubscriber('/Timing', 'std_msgs/Float64'), 0.1).Data;%app.subTime;
            send(app.pub, app.msg);
        end

        % Button pushed function: FinishServingButton
        function FinishServingButtonPushed(app, event)
            %app.FinishServing = 1;
            app.msg.ConfirmServing = 1;
            app.msg.Task = app.subHumanData.LatestMessage.Task;
            app.msg.FinishFilling = app.subHumanData.LatestMessage.FinishFilling;
            app.msg.StartFilling = app.subHumanData.LatestMessage.StartFilling;
            app.msg.FinishServing = app.subHumanData.LatestMessage.FinishServing;
            app.msg.StartServing = app.subHumanData.LatestMessage.StartServing;
            app.msg.FinishServing(app.msg.Task) = receive(rossubscriber('/Timing', 'std_msgs/Float64'), 0.1).Data;%app.subTime;
            app.msg.TimeServing(app.msg.Task) = app.msg.FinishServing(app.msg.Task) - app.msg.StartServing(app.msg.Task);
            send(app.pub, app.msg);

            % app.msg.FinishServing = 1;
            % send(app.pub, app.msg);
        end

        % Button pushed function: StartServingButton
        function StartServingButtonPushed(app, event)
            %app.StartServing = 1;

            app.msg.Task = app.subHumanData.LatestMessage.Task;
            app.msg.FinishFilling = app.subHumanData.LatestMessage.FinishFilling;
            app.msg.StartFilling = app.subHumanData.LatestMessage.StartFilling;
            app.msg.FinishServing = app.subHumanData.LatestMessage.FinishServing;
            app.msg.StartServing = app.subHumanData.LatestMessage.StartServing;
            app.msg.StartServing(app.msg.Task) = receive(rossubscriber('/Timing', 'std_msgs/Float64'), 0.1).Data;%app.subTime;
            send(app.pub, app.msg);

            % app.msg.StartServing = 1;
            % send(app.pub, app.msg);
        end

        % Callback function
        function ConfirmModifButtonPushed(app, event)
            %app.confirmModif = 1;
            send(app.pub, app.msg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.HumanInterfaceLabel = uilabel(app.UIFigure);
            app.HumanInterfaceLabel.Text = 'Human Interface';
            app.VelocityRobotReachingButtonGroup = uibuttongroup(app.UIFigure);
            app.VelocityRobotReachingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VelocityRobotReachingButtonGroupSelectionChanged, true);
            app.VelocityRobotReachingButtonGroup.Title = 'Velocity Robot Reaching';
            app.VeryfastButton = uitogglebutton(app.VelocityRobotReachingButtonGroup);
            app.VeryfastButton.Text = 'Very fast';
            app.FastButton = uitogglebutton(app.VelocityRobotReachingButtonGroup);
            app.FastButton.Text = 'Fast';
            app.OkButton = uitogglebutton(app.VelocityRobotReachingButtonGroup);
            app.OkButton.Text = 'Ok';
            app.OkButton.Value = true;
            app.SlowButton = uitogglebutton(app.VelocityRobotReachingButtonGroup);
            app.SlowButton.Text = 'Slow';
            app.VerySlowButton = uitogglebutton(app.VelocityRobotReachingButtonGroup);
            app.VerySlowButton.Text = 'Very Slow';
            app.WaitingTimeButtonGroup = uibuttongroup(app.UIFigure);
            app.WaitingTimeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaitingTimeButtonGroupSelectionChanged, true);
            app.WaitingTimeButtonGroup.Title = 'Waiting Time';
            app.TooLongButton = uitogglebutton(app.WaitingTimeButtonGroup);
            app.TooLongButton.Text = 'Too Long';
            app.LongButton = uitogglebutton(app.WaitingTimeButtonGroup);
            app.LongButton.Text = 'Long';
            app.OkButton_2 = uitogglebutton(app.WaitingTimeButtonGroup);
            app.OkButton_2.Text = 'Ok';
            app.OkButton_2.Value = true;
            app.ShortButton = uitogglebutton(app.WaitingTimeButtonGroup);
            app.ShortButton.Text = 'Short';
            app.TooShortButton = uitogglebutton(app.WaitingTimeButtonGroup);
            app.TooShortButton.Text = 'Too Short';
            app.HumanIDEditFieldLabel = uilabel(app.UIFigure);
            app.HumanIDEditFieldLabel.Text = 'Human ID';
            app.HumanIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.HumanIDEditField.ValueChangedFcn = createCallbackFcn(app, @HumanIDEditFieldValueChanged, true);
            app.HumanIDEditField.Value = 1;
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.RobotStatusEditFieldLabel = uilabel(app.UIFigure);
            app.RobotStatusEditFieldLabel.Text = 'Robot Status';
            app.RobotStatusEditField = uieditfield(app.UIFigure, 'text');
            app.RobotStatusEditField.ValueChangedFcn = createCallbackFcn(app, @RobotStatusEditFieldValueChanged, true);
            app.FinishFillingButton = uibutton(app.UIFigure, 'push');
            app.FinishFillingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishFillingButtonPushed, true);
            app.FinishFillingButton.Text = 'Finish Filling';
            app.FinishServingButton = uibutton(app.UIFigure, 'push');
            app.FinishServingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishServingButtonPushed, true);
            app.FinishServingButton.Text = 'Finish Serving';
            app.TaskEditFieldLabel = uilabel(app.UIFigure);
            app.TaskEditFieldLabel.Text = 'Task';
            app.TaskEditField = uieditfield(app.UIFigure, 'numeric');
            app.TaskEditField.ValueChangedFcn = createCallbackFcn(app, @TaskEditFieldValueChanged, true);
            app.TaskEditField.Value = 1;
            app.StartFillingButton = uibutton(app.UIFigure, 'push');
            app.StartFillingButton.ButtonPushedFcn = createCallbackFcn(app, @StartFillingButtonPushed, true);
            app.StartFillingButton.Text = 'Start Filling';
            app.StartServingButton = uibutton(app.UIFigure, 'push');
            app.StartServingButton.ButtonPushedFcn = createCallbackFcn(app, @StartServingButtonPushed, true);
            app.StartServingButton.Text = 'Start Serving';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hum1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end